classdef recordData < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        RefreshButton            matlab.ui.control.Button
        DeviceNameDropDown       matlab.ui.control.DropDown
        DeviceNameDropDownLabel  matlab.ui.control.Label
        UsernameEditField        matlab.ui.control.EditField
        UsernameEditFieldLabel   matlab.ui.control.Label
        ClearLogButton           matlab.ui.control.Button
        LogLabel                 matlab.ui.control.Label
        LogAreaLabel             matlab.ui.control.Label
        NumDoneLabel             matlab.ui.control.Label
        NextButton               matlab.ui.control.Button
        Label                    matlab.ui.control.Label
        GestureLabel             matlab.ui.control.Label
        ClicktoRecordButton      matlab.ui.control.Button
        ConnectionStatusNotconnectedLabel  matlab.ui.control.Label
        ConnectButton            matlab.ui.control.Button
    end

    
    properties (Access = private)
         connectedState = false;
         userName = '';
         gestures = []; 
         gestureInd = 0;
         recorded = false;
         hasdata = false;
    end
    
    methods (Access = private)
        function updateInfoBar(app)
            % Get the next gesture
            app.recorded = true;
            app.gestureInd = app.gestureInd + 1;
            if app.gestureInd <= numel(app.gestures)
                app.NumDoneLabel.Text = sprintf('%d/%d', app.gestureInd, numel(app.gestures));
                gestureName = "";
                switch app.gestures(app.gestureInd)
                    case 1
                        gestureName = "Rock";
                    case 2
                        gestureName = "Paper";
                    case 3
                        gestureName = "Scissor";
                end
                % Update info bar with new gesture name
                app.Label.Text = gestureName;
                % Change record button back
                app.recorded = false;
                app.hasdata = false;
                app.ClicktoRecordButton.Text = "Click to Record";
            else
                app.NumDoneLabel.Text = "Completed gestures!";
                app.Label.Text = "Done!";
                app.recorded = false;
                app.ClicktoRecordButton.Text = "Click to Record";
                if app.connectedState
                % Look to disconnect
                    try
                        pyrun("lsl.end_stream_listener()");
                    catch ME
                        err_text = sprintf("Error disconnecting stream listener!\nFull python error:\n%s\n%s",...
                                    ME.message);
                        app.LogLabel.Text = err_text;
                    end
                    app.connectedState = false;
                    app.ConnectButton.Text = "Connect";
                    app.ConnectionStatusNotconnectedLabel.Text = "Not connected";
                    gesturelist = app.gestures;
                    save("gestures.mat", "gesturelist");
                    app.LogAreaLabel.Text = "Results saved!";
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            pyrun("from get_lsl_data import PyLSLWrapper, get_all_streams");
            app.RefreshPushed();
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            deviceId = app.DeviceNameDropDown.Value;
            username = app.UsernameEditField.Value;
            s = [ones(1,10) ones(1,10)*2, ones(1,10)*3];
            gest = s(randperm(numel(s)));
            app.gestures = gest;
            save('gestures.mat', 'gest')
            disp(gest)
            disp(deviceId)
            if app.connectedState
            % Look to disconnect
                try
                    pyrun("lsl.end_stream_listener()");
                catch ME
                    err_text = sprintf("Error disconnecting stream listener!\nFull python error:\n%s\n%s",...
                                ME.message);
                    app.LogLabel.Text = err_text;
                end
                app.connectedState = false;
                app.ConnectButton.Text = "Connect";
                app.ConnectionStatusNotconnectedLabel.Text = "Not connected";
                app.LogAreaLabel.Text = "Results saved!";
            elseif deviceId ~= "" && username ~= ""
                app.ConnectButton.Text = "Connecting...";
                app.ConnectionStatusNotconnectedLabel.Text = "";
                drawnow;
                start_call = sprintf('lsl = PyLSLWrapper("%s")', deviceId);
                app.userName = username;
                disp(start_call)
                try
                    pyrun(start_call);
                catch ME
                    err_text = sprintf("Error importing!\nFull python error:\n%s\n%s",...
                        ME.message);
                    app.LogLabel.Text = err_text;
                end
                try
                    % wrap this code in try catch because it may error if
                    % LSL is not able to find the stream
                    pyrun("lsl.launch_stream_listener()")
                    app.ConnectionStatusNotconnectedLabel.Text = ['Connected and recording from device with ID ' deviceId];
                    app.ConnectButton.Text = "Disconnect";
                    app.connectedState = true;
                    % Set info bar to be ready for gesture collection
                    app.updateInfoBar();
                    app.NextButton.Text = "...";
                catch ME
                    app.ConnectionStatusNotconnectedLabel.Text = "Error connecting to device--check that device is on and ID is correct";
                    err_text = sprintf("Error connecting!\nFull python error:\n%s", ME.message);
                    app.LogLabel.Text = err_text;
                    app.ConnectButton.Text = "Connect";
                end     
            else
                app.ConnectionStatusNotconnectedLabel.Text = "Please enter a device ID";
            end
        end

        % Button pushed function: ClicktoRecordButton
        function clickRecord(app, event)
            if ~app.recorded && app.connectedState
                % if app is not recording or not done recording send an lsl
                % marker to stream after countdown
                tic;
                lastTime = toc;
                time = 3;
                if app.ClicktoRecordButton.Text == "Click to re-record"
                    pyrun('lsl.edit_last_marker()');
                end
                app.ClicktoRecordButton.Text = int2str(time);
                drawnow;
                sentMarker = false;
                app.recorded = true;
                while toc < 4
                    if toc >= lastTime + 1
                        lastTime = toc;
                        time = time - 1;
                        if time ~= 0
                            app.ClicktoRecordButton.Text = int2str(time);
                            drawnow;
                        else
                            app.ClicktoRecordButton.Text = "Shoot!";
                            drawnow;
                        end
                    end
                    if toc >= 2.5 && ~sentMarker
                        sentMarker = true;
                        % marker no. corresponds to trial no.
                        pythonCall = sprintf('lsl.add_marker(%d)', app.gestures(app.gestureInd));
                        try
                            pyrun(pythonCall); 
                        catch ME
                            err_text = sprintf("Error adding marker!\nFull python error:\n%s\n%s",...
                                ME.message);
                            app.LogLabel.Text = err_text;
                        end
                    end
                end
                pythonCall = sprintf('lsl.add_marker(%d)', 0);
                try
                    pyrun(pythonCall); 
                catch ME
                    err_text = sprintf("Error adding marker!\nFull python error:\n%s\n%s",...
                        ME.message);
                    app.LogLabel.Text = err_text;
                end
                app.NextButton.Text = "Next";
                app.ClicktoRecordButton.Text = "Click to re-record";
                app.recorded = false;
                app.hasdata = true;
            end 
        end

        % Button pushed function: NextButton
        function NextButtonCallback(app, event)
            if app.hasdata && app.connectedState
                app.updateInfoBar();
                app.NextButton.Text = "...";
            end
        end

        % Button pushed function: ClearLogButton
        function ClearLogPushed(app, event)
            app.LogLabel.Text = "";
        end

        % Button pushed function: RefreshButton
        function RefreshPushed(app, event)
            stream_list = string(pyrun('streams = get_all_streams()', 'streams'));
            app.DeviceNameDropDown.Items = stream_list;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.ConnectionStatusNotconnectedLabel = uilabel(app.UIFigure);
            app.ConnectionStatusNotconnectedLabel.Text = 'Connection Status: Not connected';
            app.ClicktoRecordButton = uibutton(app.UIFigure, 'push');
            app.ClicktoRecordButton.ButtonPushedFcn = createCallbackFcn(app, @clickRecord, true);
            app.ClicktoRecordButton.Text = 'Click to Record';
            app.GestureLabel = uilabel(app.UIFigure);
            app.GestureLabel.Text = 'Gesture: ';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonCallback, true);
            app.NextButton.Tag = 'NextButton';
            app.NextButton.Text = 'Next';
            app.NumDoneLabel = uilabel(app.UIFigure);
            app.NumDoneLabel.Text = 'Num Done';
            app.LogAreaLabel = uilabel(app.UIFigure);
            app.LogAreaLabel.Text = 'Log Area:';
            app.LogLabel = uilabel(app.UIFigure);
            app.LogLabel.Text = '';
            app.ClearLogButton = uibutton(app.UIFigure, 'push');
            app.ClearLogButton.ButtonPushedFcn = createCallbackFcn(app, @ClearLogPushed, true);
            app.ClearLogButton.Text = 'Clear Log';
            app.UsernameEditFieldLabel = uilabel(app.UIFigure);
            app.UsernameEditFieldLabel.Text = 'Username';
            app.UsernameEditField = uieditfield(app.UIFigure, 'text');
            app.DeviceNameDropDownLabel = uilabel(app.UIFigure);
            app.DeviceNameDropDownLabel.Text = 'Device Name';
            app.DeviceNameDropDown = uidropdown(app.UIFigure);
            app.DeviceNameDropDown.Items = {};
            app.DeviceNameDropDown.Value = {};
            app.RefreshButton = uibutton(app.UIFigure, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshPushed, true);
            app.RefreshButton.Text = 'Refresh';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = recordData

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end