classdef testData < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        UsernameEditField         matlab.ui.control.EditField
        UsernameEditFieldLabel    matlab.ui.control.Label
        ClearLogButton            matlab.ui.control.Button
        LogLabel                  matlab.ui.control.Label
        LogAreaLabel              matlab.ui.control.Label
        Label                     matlab.ui.control.Label
        ClicktoRecordButton       matlab.ui.control.Button
        ConnectionStatusNotconnectedLabel  matlab.ui.control.Label
        DeviceNameEditField       matlab.ui.control.EditField
        DeviceNameEditFieldLabel  matlab.ui.control.Label
        ConnectButton             matlab.ui.control.Button
    end

    
    properties (Access = private)
         connectedState = false;
         userName = '';
         recorded = false;
         hasdata = false;
         markers = [];
    end

    methods (Access = public)
        function res = processLSLData(app,dataMat)
            % Code here to process the lsl data matrix
            % right now just always predict rock
            res = 1;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            deviceId = app.DeviceNameEditField.Value;
            username = app.UsernameEditField.Value;
            disp(deviceId)
            if app.connectedState
            % Look to disconnect
                try
                    pyrun("lsl.end_stream_listener()");
                catch ME
                    err_text = sprintf("Error disconnecting stream listener!\nFull python error:\n%s\n%s",...
                                ME.message);
                    app.LogLabel.Text = err_text;
                end
                app.connectedState = false;
                app.ConnectButton.Text = "Connect";
                app.ConnectionStatusNotconnectedLabel.Text = "Not connected";
                app.LogAreaLabel.Text = "Results saved!";
            elseif deviceId ~= "" && username ~= ""
                app.ConnectButton.Text = "Connecting...";
                app.ConnectionStatusNotconnectedLabel.Text = "";
                drawnow;
                start_call = sprintf('lsl = PyLSLWrapper("%s")', deviceId);
                app.userName = username;
                disp(start_call)
                try
                    pyrun(["from get_lsl_data import PyLSLWrapper", start_call]);
                catch ME
                    err_text = sprintf("Error importing!\nFull python error:\n%s\n%s",...
                        ME.message);
                    app.LogLabel.Text = err_text;
                end
                try
                    % wrap this code in try catch because it may error if
                    % LSL is not able to find the stream
                    pyrun("lsl.launch_stream_listener()")
                    app.ConnectionStatusNotconnectedLabel.Text = ['Connected and recording from device with ID ' deviceId];
                    app.ConnectButton.Text = "Disconnect";
                    app.connectedState = true;
                    % Set info bar to be ready for gesture collection
                catch ME
                    app.ConnectionStatusNotconnectedLabel.Text = "Error connecting to device--check that device is on and ID is correct";
                    err_text = sprintf("Error connecting!\nFull python error:\n%s", ME.message);
                    app.LogLabel.Text = err_text;
                    app.ConnectButton.Text = "Connect";
                end
                % Create the outlet for the marker stream\
                pyrun('lsl.push_stream()');
            else
                app.ConnectionStatusNotconnectedLabel.Text = "Please enter a device ID";
            end
        end

        % Button pushed function: ClicktoRecordButton
        function clickRecord(app, event)
            if ~app.recorded && app.connectedState
                % if app is not recording or not done recording send an lsl
                % marker to stream after countdown
                tic;
                lastTime = toc;
                time = 3;
                app.ClicktoRecordButton.Text = int2str(time);
                drawnow;
                app.recorded = true;
                tstamp = pyrun('tstamp = lsl.get_curr_timestamp()', 'tstamp');
                while toc < 6
                    if toc >= lastTime + 1
                        lastTime = toc;
                        time = time - 1;
                        if time > 0
                            app.ClicktoRecordButton.Text = int2str(time);
                            drawnow;
                        elseif time > -1
                            app.ClicktoRecordButton.Text = "Shoot!";
                            drawnow;
                        else
                            app.ClicktoRecordButton.Text = "Done!";
                            drawnow;
                        end
                    end
                end
                disp('getting data')
                pycall = sprintf('arr = lsl.get_data_from(%d)', tstamp);
                pyarr = double(pyrun(pycall, 'arr'));
                res = app.processLSLData(pyarr);
                disp('pushing inference')
                pycall = sprintf('lsl.push_to_stream(%d)', res);
                pyrun(pycall)
                app.ClicktoRecordButton.Text = "Click to Record";
                app.recorded = false;
                app.hasdata = true;
            end 
        end

        % Callback function
        function NextButtonCallback(app, event)
            if app.hasdata && app.connectedState
                app.updateInfoBar();
                app.NextButton.Text = "...";
            end
        end

        % Button pushed function: ClearLogButton
        function ClearLogPushed(app, event)
            app.LogLabel.Text = "";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.DeviceNameEditFieldLabel = uilabel(app.UIFigure);
            app.DeviceNameEditFieldLabel.Text = 'Device Name';
            app.DeviceNameEditField = uieditfield(app.UIFigure, 'text');
            app.ConnectionStatusNotconnectedLabel = uilabel(app.UIFigure);
            app.ConnectionStatusNotconnectedLabel.Text = 'Connection Status: Not connected';
            app.ClicktoRecordButton = uibutton(app.UIFigure, 'push');
            app.ClicktoRecordButton.ButtonPushedFcn = createCallbackFcn(app, @clickRecord, true);
            app.ClicktoRecordButton.Text = 'Click to Record';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.LogAreaLabel = uilabel(app.UIFigure);
            app.LogAreaLabel.Text = 'Log Area:';
            app.LogLabel = uilabel(app.UIFigure);
            app.LogLabel.Text = '';
            app.ClearLogButton = uibutton(app.UIFigure, 'push');
            app.ClearLogButton.ButtonPushedFcn = createCallbackFcn(app, @ClearLogPushed, true);
            app.ClearLogButton.Text = 'Clear Log';
            app.UsernameEditFieldLabel = uilabel(app.UIFigure);
            app.UsernameEditFieldLabel.Text = 'Username';
            app.UsernameEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = testData

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end